classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CLEARButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        StressLifeTab                   matlab.ui.container.Tab
        CyclesLabel_3                   matlab.ui.control.Label
        CyclestoFailureEditField        matlab.ui.control.NumericEditField
        CyclestoFailureEditFieldLabel   matlab.ui.control.Label
        BlocksLabel                     matlab.ui.control.Label
        RepetitionsEditField            matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel       matlab.ui.control.Label
        CumulativeDamageTWISTEditField  matlab.ui.control.NumericEditField
        CumulativeDamageTWISTEditFieldLabel  matlab.ui.control.Label
        DamageButton                    matlab.ui.control.Button
        CyclesLabel                     matlab.ui.control.Label
        LifeEditField                   matlab.ui.control.NumericEditField
        LifeEditFieldLabel              matlab.ui.control.Label
        CalculateLifeButton             matlab.ui.control.Button
        STRESSLIFETOTALLIFECALCULATIONLabel  matlab.ui.control.Label
        InputsPanel                     matlab.ui.container.Panel
        StressLevel                     matlab.ui.control.TextArea
        PaLabel                         matlab.ui.control.Label
        SelectGeometryDropDown          matlab.ui.control.DropDown
        SelectGeometryDropDownLabel     matlab.ui.control.Label
        StressEditField                 matlab.ui.control.NumericEditField
        StressEditFieldLabel            matlab.ui.control.Label
        MeanStressCorrectionCriteriaDropDown  matlab.ui.control.DropDown
        MeanStressCorrectionCriteriaDropDownLabel  matlab.ui.control.Label
        EnduranceCorrectionButton       matlab.ui.control.Button
        StressRatioREditField           matlab.ui.control.NumericEditField
        StressRatioREditFieldLabel      matlab.ui.control.Label
        AppliedStressofyieldEditField   matlab.ui.control.NumericEditField
        AppliedStressofyieldEditFieldLabel  matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialDropDownLabel           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        CrackGrowthTab                  matlab.ui.container.Tab
        mmLabel                         matlab.ui.control.Label
        CriticalSizeEditField           matlab.ui.control.NumericEditField
        CriticalSizeEditFieldLabel      matlab.ui.control.Label
        CRACKGROWTHLabel                matlab.ui.control.Label
        IncaseofOverloadInteractionputinputsfirstLabel  matlab.ui.control.Label
        OLInteractionInputsPanel        matlab.ui.container.Panel
        AlandTi35SteelLabel             matlab.ui.control.Label
        OverloadCycleEditField          matlab.ui.control.NumericEditField
        OverloadCycleEditFieldLabel     matlab.ui.control.Label
        OverloadSIFSIFEditField         matlab.ui.control.NumericEditField
        OverloadSIFSIFEditFieldLabel    matlab.ui.control.Label
        CrackGeometryDropDown           matlab.ui.control.DropDown
        CrackGeometryDropDownLabel      matlab.ui.control.Label
        CyclesLabel_2                   matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        MPasqrtmmLabel                  matlab.ui.control.Label
        CriticalSIFLabel                matlab.ui.control.Label
        GrowthLifeEditField_2           matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        GrowthLifeEditField_2Label      matlab.ui.control.Label
        ComputeButton                   matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        StrainLifeTab                   matlab.ui.container.Tab
        TransitionReversalsEditField    matlab.ui.control.NumericEditField
        TransitionReversalsEditFieldLabel  matlab.ui.control.Label
        ComputeButton_2                 matlab.ui.control.Button
        CyclesEditField                 matlab.ui.control.NumericEditField
        CyclesEditFieldLabel            matlab.ui.control.Label
        ReversalsEditField              matlab.ui.control.NumericEditField
        ReversalsEditFieldLabel         matlab.ui.control.Label
        StrainAmplitudeEditField        matlab.ui.control.NumericEditField
        StrainAmplitudeEditFieldLabel   matlab.ui.control.Label
        PaLabel_2                       matlab.ui.control.Label
        MaximumStressEditField          matlab.ui.control.NumericEditField
        MaximumStressEditFieldLabel     matlab.ui.control.Label
        STRAINLIFELIFECALCULATIONLabel  matlab.ui.control.Label
        InputsPanel_2                   matlab.ui.container.Panel
        InitialLifeGuessEditField       matlab.ui.control.NumericEditField
        InitialLifeGuessEditFieldLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        MeanCorrectionDropDown          matlab.ui.control.DropDown
        MeanCorrectionDropDownLabel     matlab.ui.control.Label
        NumberofDecimalPlacesEditField  matlab.ui.control.NumericEditField
        NumberofDecimalPlacesEditFieldLabel  matlab.ui.control.Label
        SelectGeometryDropDown_2        matlab.ui.control.DropDown
        SelectGeometryDropDown_2Label   matlab.ui.control.Label
        AppliedStressofyieldEditField_2  matlab.ui.control.NumericEditField
        AppliedStressofyieldEditField_2Label  matlab.ui.control.Label
        StressRatioREditField_2         matlab.ui.control.NumericEditField
        StressRatioREditField_2Label    matlab.ui.control.Label
        SelectMaterialDropDown          matlab.ui.control.DropDown
        SelectMaterialDropDownLabel     matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        StrainLifeDamageTab             matlab.ui.container.Tab
        CyclesLabel_4                   matlab.ui.control.Label
        CyclestoFailureEditField_2      matlab.ui.control.NumericEditField
        CyclestoFailureEditField_2Label  matlab.ui.control.Label
        BlocksLabel_2                   matlab.ui.control.Label
        RepetitionsEditField_2          matlab.ui.control.NumericEditField
        RepetitionsEditField_2Label     matlab.ui.control.Label
        CumulativeDamageTWISTEditField_2  matlab.ui.control.NumericEditField
        CumulativeDamageTWISTEditField_2Label  matlab.ui.control.Label
        ComputeButton_3                 matlab.ui.control.Button
    end


    properties (Access = private)
        Corrected_Stress % corrected stress value
        Mean_Stress
        Yield_Strength
        Ultimate_Strength
        Applied_Stress
        R
        Density
        E
        Poisson
        FS
        KIC
        N_failure
        Smax_Sgo
        Alpha
        K1c
        YS
        Ak
        Bk
        Kc
        DK0
        Cth_p
        p
        q
        C
        n
        K % stress concentration factor
        ai % Initial Crack Size from NASGRO
        Kth
        Ng % Growth Life
        N_total % Description
        CG % Crack Geometry
        method % Crack Growth method
        Kcrit % Critical SIF
        size % Variable for crack size
        growth_life % N_life_growth variable
        RSO % Shutoff value 
        K_overload_percen % max Overload Stress Intensity factor
        i_OL % Overload Cycle
        Damage %Cumulative Damage
        ac % Critical Crack Size
        N_rep % Repetitions
        Yield_Strength_prime % Tensile Yield Strength cyclic
        K_prime % Cyclic Strength Coefficient 
        n_prime % Cyclic Strain Hardening Exponent
        sigma_f_prime % Cyclic Fatigue Strength Coefficient
        epsilon_f_prime % Cyclic Fatigue Ductility Coefficient
        bbb % Fatigue Strength Exponent
        ccc % Fatigue Ductility Exponent
        Density_2 % Density for Strain Life
        Ultimate_Strength_2 % Tensile Ultimate Strength for Strain Life
        Yield_Strength_2 % Tensile Yield Strength for Strain Life
        E_2 % Modulus of Elasticity for Strain Life
        Poisson_2 % Poisson Ratio for Strain Life
        R_2 % Stress Ratio for Strain Life
        Applied_Stress_2 % Applied Stress for Strain Life
        Mean_Stress_2 % Mean Stress for Strain Life
        K_f % Fatigue Stress Concentration for Strain Life
        Corrected_Stress_2 % Corrected Stress for Strain Life
        Corrected_Strain % Corrected Strain for Strain Life
        Decimal % Decimal Places
        Mean_Correction % Mean Correction Value for Stress
        Reversals % Reversals for Strain Life
        ILG % Initial Life Guess
        IRG % Initial Reversal Guess
        Max_Stress % Max Stress for Strain Life
        Damage_Strain % Damage for strain life
        N_rep_strain % Repetition Blocks for Strain Life
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged(app, event)
            value = app.MaterialDropDown.Value;
            switch value
                case 'Al-2024-T6'
                app.Density = 2780;    % Density in kg/m3
                app.Ultimate_Strength = 415*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength = 315*10^6; % Tensile Yield Strength in Pa
                app.E = 72.4*10^9;      % Modulus of Elasticity in Pa
                app.Poisson = 0.33;        % Poisson Ratio
                app.FS = 124*10^6;       % Fatigue Strength in Pa at 5e008 cycles
                app.KIC = 35*10^6;       % Fracture Toughness in Pa.m^1/2
                % NASGRO Equation Parameters
                app.Smax_Sgo = 0.30;
                app.Alpha = 1.5;
                app.K1c = 1251; % in MPa*sqrt(mm) Plane Strain
                app.YS = 315; % Tensile Yield Strength in MPa
                app.Ak = 1.0; app.Bk = 1.0;
                app.Kc = 75.5*10^3;% in psi*sqrt(in)Plane Stress
                app.DK0 = 101;% in MPa*sqrt(mm)Threshold SIF range at R=0;
                app.Cth_p = 1.5;% Threshold Coefficient
                app.p = 0.5;app.q = 1;
                app.C = 0.298e-11;% Paris Crack Growth Rate Constant
                app.n = 3.2;% Paris Exponent for NASGRO Equations
                % Generalized Willenborg
                app.RSO = 2.3;% C026_ABM01_Comparing_overload.pdf

                
                case 'Al-7075-T6'
                app.Density = 2810;    % Density in kg/m3
                app.Ultimate_Strength = 510*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength = 434*10^6; % Tensile Yield Strength in Pa
                app.E = 71.7*10^9;      % Modulus of Elasticity in Pa
                app.Poisson = 0.33;        % Poisson Ratio
                app.FS = 159*10^6;       % Fatigue Strength in Pa at 5e008 cycles
                app.KIC = 25*10^6;       % Fracture Toughness in Pa.m^1/2
                % NASGRO Equation Parameters
                app.Smax_Sgo = 0.30;
                app.Alpha = 1.9;
                app.K1c = 938; % in MPa*sqrt(mm) Plane Strain
                app.YS = 434; % Tensile Yield Strength in MPa
                app.Ak = 1; app.Bk = 1;
                app.Kc = 49.9*10^3;% in psi*sqrt(in)Plane Stress
                app.DK0 = 104;% in MPa*sqrt(mm)Threshold SIF range at R=0;
                app.Cth_p = 2.0;% Threshold Coefficient
                app.p = 0.5;app.q = 1;
                app.C = 0.153e-10;% Paris Crack Growth Rate Constant
                app.n = 2.947;% Paris Exponent for NASGRO Equations
                % Generalized Willenborg
                app.RSO = 2.3;% C026_ABM01_Comparing_overload.pdf
                
                
                case 'Al-7075-T73'
                app.Density = 2810;    % Density in kg/m3
                app.Ultimate_Strength = 462*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength = 386*10^6; % Tensile Yield Strength in Pa
                app.E = 72*10^9;      % Modulus of Elasticity in Pa
                app.Poisson = 0.33;        % Poisson Ratio
                app.FS = 150*10^6;       % Fatigue Strength in Pa at 5e008 cycles
                app.KIC = 22*10^6;       % Fracture Toughness in Pa.m^1/2
                % NASGRO Equation Parameters
                app.Smax_Sgo = 0.30;
                app.Alpha = 1.9;
                app.K1c = 973; % in MPa*sqrt(mm) Plane Strain
                app.YS = 386; % Tensile Yield Strength in MPa
                app.Ak = 1.0; app.Bk = 1.0;
                app.Kc = 53.8*10^3;% in psi*sqrt(in)Plane Stress
                app.DK0 = 104;% in MPa*sqrt(mm)Threshold SIF range at R=0;
                app.Cth_p = 2.0;% Threshold Coefficient
                app.p = 0.5;app.q = 1.0;
                app.C = 0.289e-11;% Paris Crack Growth Rate Constant
                app.n = 3.321;% Paris Exponent for NASGRO Equations
                % Generalized Willenborg
                app.RSO = 2.3;% C026_ABM01_Comparing_overload.pdf
                
            
                case 'Ti-6Al-4V'
                app.Density = 4430;    % Density in kg/m3
                app.Ultimate_Strength = 860*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength = 790*10^6; % Tensile Yield Strength in Pa
                app.E = 113.8*10^9;      % Modulus of Elasticity in Pa
                app.Poisson = 0.342;        % Poisson Ratio
                app.FS = 140*10^6;       % Fatigue Strength in Pa at 1e007 cycles
                app.KIC = 100*10^6;       % Fracture Toughness in Pa.m^1/2
                % NASGRO Equation Parameters
                app.Smax_Sgo = 0.30;
                app.Alpha = 2.5;
                app.K1c = 1737; % in MPa*sqrt(mm) Plane Strain
                app.YS = 790; % Tensile Yield Strength in MPa
                app.Ak = 1.0; app.Bk = 0.5;
                app.DK0 = 122;% in MPa*sqrt(mm)Threshold SIF range at R=0;
                app.Cth_p = 1.8;% Threshold Coefficient
                app.p = 0.25;app.q = 0.75;
                app.C = 0.147e-11;% Paris Crack Growth Rate Constant
                app.n = 3.01;% Paris Exponent for NASGRO Equations
                % Generalized Willenborg
                app.RSO = 2.3;% C026_ABM01_Comparing_overload.pdf
       

                case 'Steel-17-4Ph'
                app.Density = 7810;    % Density in kg/m3
                app.Ultimate_Strength = 1207*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength = 1148*10^6; % Tensile Yield Strength in Pa
                app.E = 196*10^9;      % Modulus of Elasticity in Pa
                app.Poisson = 0.27;        % Poisson Ratio
                app.KIC = 67.8*10^6;       % Fracture Toughness in Pa.m^1/2  
                app.FS = 0.5*app.Ultimate_Strength;       % Endurance Strength of Steel based on shingley criteria.
                % NASGRO Equation Parameters
                app.Smax_Sgo = 0.30;
                app.Alpha = 2.5;
                app.K1c = 1737; % in MPa*sqrt(mm) Plane Strain
                app.YS = 1148; % Tensile Yield Strength in MPa
                app.Ak = 1.0; app.Bk = 0.5;
                app.DK0 = 139;% in MPa*sqrt(mm)Threshold SIF range at R=0;
                app.Cth_p = 0.8;% Threshold Coefficient
                app.p = 0.25;app.q = 0.25;
                app.C = 0.246e-12;% Paris Crack Growth Rate Constant
                app.n = 3.11;% Paris Exponent for NASGRO Equations
                % Generalized Willenborg
                app.RSO = 3.5;% C026_ABM01_Comparing_overload.pdf
                
            end
        end

        % Value changed function: StressRatioREditField
        function StressRatioREditFieldValueChanged(app, event)
          app.R = app.StressRatioREditField.Value;
        end

        % Value changed function: AppliedStressofyieldEditField
        function AppliedStressofyieldEditFieldValueChanged(app, event)
            value = app.AppliedStressofyieldEditField.Value;
            app.Applied_Stress = value*app.Yield_Strength;
            A = (1-app.R)/(1+app.R);
            app.Mean_Stress = app.Applied_Stress/A;
            if app.R==-1
              app.Corrected_Stress=app.Applied_Stress;
              app.StressEditField.Value = app.Corrected_Stress;
        elseif app.R==0
                app.Mean_Stress = app.Applied_Stress;
            end
           
        end

        % Value changed function: 
        % MeanStressCorrectionCriteriaDropDown
        function MeanStressCorrectionCriteriaDropDownValueChanged(app, event)
            value = app.MeanStressCorrectionCriteriaDropDown.Value;
            app.Corrected_Stress = Mean_Stress_Correction(value,app.Mean_Stress,app.Applied_Stress,app.Ultimate_Strength,app.Yield_Strength);
            app.StressEditField.Value = app.Corrected_Stress;
        end

        % Button pushed function: CalculateLifeButton
        function CalculateLifeButtonPushed(app, event)
            b = -(1/3).*log10(app.FS\(0.75*app.Ultimate_Strength));
            a = ((0.75.*app.Ultimate_Strength)^2)/app.FS; % 0.75 is for the axial factor HCF approach
%             b = (1/6).*log10(app.FS/app.Ultimate_Strength);
%             a = app.Ultimate_Strength;
            app.N_failure = (app.Corrected_Stress/a)^(1/b);
            app.LifeEditField.Value = app.N_failure;
            Stress = linspace(app.FS,app.Ultimate_Strength,50);
            N_life = (Stress./a).^(1./b);
            xq = app.FS:10:app.Ultimate_Strength;
            vq = interpn(Stress,N_life,xq,'spline');
            semilogx(app.UIAxes,N_life,Stress,'-o',vq,xq,'lineWidth',1)
                if app.Corrected_Stress < app.FS
                    app.N_failure = 1/0;
                    app.LifeEditField.Value = app.N_failure;
                end
        end

        % Button pushed function: EnduranceCorrectionButton
        function EnduranceCorrectionButtonPushed(app, event)
            C_load = 0.85;% Loading factor
            h = 1000; % mm height
            w = 500; % mm width of rectangular plane stress specimen
%             h = 750; % mm height % These dimensions are for the MSD 2 hole plate
%             w = 200; % mm width of rectangular plane stress specimen

            de = 0.808*sqrt(h*w);
            if de>=2.79 && de<=51
                C_size = 1.24*(de^-0.107);% Size factor
            elseif de>51 
                C_size = 1.51*(de^-0.157);
            end
            aa = 57.7; % MPa
            bb = -0.718;
            C_surf = aa*((app.Ultimate_Strength*10^-6)^bb); % Surface factor for Machined specimen
            C_temp = 1; % Temperature factor for less than 450 C
            C_relia = 0.868; % Reliability factor for 95 % reliability
            app.FS = C_load*C_size*C_surf*C_temp*C_relia*app.FS;
        end

        % Value changed function: SelectGeometryDropDown
        function SelectGeometryDropDownValueChanged(app, event)
            value = app.SelectGeometryDropDown.Value;
            switch value
                case 'Flat Plate'
                    app.K = 1.0;
                    app.Corrected_Stress = app.K*app.Corrected_Stress;
                    app.StressEditField.Value = app.Corrected_Stress;
                    app.StressLevel.Value = {'Proceed to Life Calculation'};
                case 'Circular Hole'
                    a = 75;% mm diameter of hole
                    d = 500;% mm width of plate
                    app.K = 3*d/(a+d);
                    app.Corrected_Stress = app.K*app.Corrected_Stress;
                    app.StressEditField.Value = app.Corrected_Stress;
                    if app.Corrected_Stress>=app.Yield_Strength
                        app.StressLevel.Value = {'Applied stress exceeds yield strength; Adjust applied stress values'};
                    else
                        app.StressLevel.Value = {'Proceed to Life Calculation'};
                    end
                case 'Elliptical Hole'
                    a = 4;% mm semi major axis
                    b = 3;% mm semi minor axis
                    app.K = 1 + 2*(a/b);
                    app.Corrected_Stress = app.K*app.Corrected_Stress;
                    app.StressEditField.Value = app.Corrected_Stress;
                    if app.Corrected_Stress>=app.Yield_Strength
                        app.StressLevel.Value = {'Applied stress exceeds yield strength; Adjust applied stress values'};
                    else
                        app.StressLevel.Value = {'Proceed to Life Calculation'};
                    end
                case 'Edge Notch'
                    h = 0.5;% mm notch height
                    r = 0.3;% mm radius of notch
                    app.K = 1+2*sqrt(h/r);
                    app.Corrected_Stress = app.K*app.Corrected_Stress;
                    app.StressEditField.Value = app.Corrected_Stress;
                    if app.Corrected_Stress>=app.Yield_Strength
                        app.StressLevel.Value = {'Applied stress exceeds yield strength; Adjust applied stress values'};
                    else
                        app.StressLevel.Value = {'Proceed to Life Calculation'};
                    end
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.EditField_4.Value = app.Kcrit;
            app.GrowthLifeEditField_2.Value = app.Ng;
            app.N_total = app.Ng;
            plot(app.UIAxes2,app.growth_life,app.size,'*','linewidth',1)
        end

        % Value changed function: CrackGeometryDropDown
        function CrackGeometryDropDownValueChanged(app, event)
            app.CG = app.CrackGeometryDropDown.Value;
             switch app.CG
                case 'Edge Crack'
                    app.ai = 2.540;% mm from NASGRO
                case 'Center Crack'
%                     app.ai = 1.270;% mm from NASGRO
                      app.ai = 3;% for MSD 2 hole plate
                 case 'Center Hole'
                    app.ai = 2.540;% mm from NASGRO % i put 5mm for MSD just to check
            end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            app.method = app.MethodDropDown.Value;
            switch app.method
                case 'Paris Law'
                    t = 5;
                    Sig_max = (app.Applied_Stress+app.Mean_Stress)/(10^6);
                    Y = Crack_Shape(app.CG,app.ai);
                    Kmax = MaxSIF(Sig_max,Y,app.ai);
                    DelK = SIFRange(Kmax,app.R);
                    t0 = 2.5*((app.K1c/app.YS)^2);
                    app.Kcrit = app.K1c*(1+app.Bk*exp(-(app.Ak*t/t0)));
                    StepSize = 1*10^-2;% Step Size for crack growth
                    N_growth = 0;
                    A = zeros([1 100000]);
                    N = zeros([1 100000]);
                    for i = 1:100000
                        dN_da = (1./(app.C.*(DelK.^app.n)));
                        A(i) = app.ai;
                        app.ai = app.ai+StepSize;
                        N_growth = N_growth+StepSize*dN_da;
                        Y = Crack_Shape(app.CG,app.ai);
                        Kmax = MaxSIF(Sig_max,Y,app.ai);
                        DelK = SIFRange(Kmax,app.R);
                        N(i) = N_growth;
                        if Kmax>=app.Kcrit
                            break
                        end
                    end
                    app.ac = (1/pi)*((app.Kcrit/(Y*Sig_max))^2);
                    app.CriticalSizeEditField.Value = app.ac;
                    app.size = A;
                    app.growth_life = N;
                    app.Ng = N_growth; 
                    value(app.Ng)
                case 'No Interaction'
                    A0 = (0.825-0.34*app.Alpha+0.05*(app.Alpha^2))*((cos(pi/2*app.Smax_Sgo))^(1/app.Alpha));
                    A1 = (0.415-0.071*app.Alpha)*app.Smax_Sgo;
                    A3 = 2*A0+A1-1;
                    A2 = 1-A0-A1-A3;
                    if app.R>=-2&&app.R<0
                        f = A0+A1*app.R;
                    elseif app.R>=0
                        f = A0+A1*app.R+A2*(app.R^2)+A3*(app.R^3);
                        if f>app.R
                            f = f+0;
                        elseif f<=app.R
                            f = app.R;
                        end
                    end
                    %% Threshold Range
                    t = 20;
                    Sig_max = (app.Applied_Stress+app.Mean_Stress)/(10^6);
                    Y = Crack_Shape(app.CG,app.ai);
                    Kmax = MaxSIF(Sig_max,Y,app.ai);
                    DelK = SIFRange(Kmax,app.R);
                    t0 = 2.5*((app.K1c/app.YS)^2);
                    app.Kcrit = app.K1c*(1+app.Bk*exp(-(app.Ak*t/t0)));
                    DelKth = Threshold(app.ai,app.DK0,f,A0,app.R,app.Cth_p);
                    StepSize = 1*10^-2;% Step Size for crack growth
                    N_growth = 0;
                    A = zeros([1 100000]);
                    N = zeros([1 100000]);
                    for i = 1:100000
                        dN_da = ((1-Kmax/app.Kcrit)^app.p)/(app.C*((((1-f)/(1-app.R))*(DelK))^app.n)*((1-DelKth/DelK)^app.q));
                        A(i) = app.ai;
                        app.ai = app.ai+StepSize;
                        N_growth = N_growth+StepSize*dN_da;
                        Y = Crack_Shape(app.CG,app.ai);
                        Kmax = MaxSIF(Sig_max,Y,app.ai);
                        DelK = SIFRange(Kmax,app.R);
                        DelKth = Threshold(app.ai,app.DK0,f,A0,app.R,app.Cth_p);
                        N(i) = N_growth;
                        if Kmax>=app.Kcrit
                            break
                        end
                    end
                    app.ac = (1/pi)*((app.Kcrit/(Y*Sig_max))^2);
                    app.CriticalSizeEditField.Value = app.ac;
                    app.size = A;
                    app.growth_life = N;
                    app.Ng = N_growth; 
                    value(app.Ng)
                case 'Overload Interaction'
                    A0 = (0.825-0.34*app.Alpha+0.05*(app.Alpha^2))*((cos(pi/2*app.Smax_Sgo))^(1/app.Alpha));
                    A1 = (0.415-0.071*app.Alpha)*app.Smax_Sgo;
                    A3 = 2*A0+A1-1;
                    A2 = 1-A0-A1-A3;
                    if app.R>=-2&&app.R<0
                        f = A0+A1*app.R;
                    elseif app.R>=0
                        f = A0+A1*app.R+A2*(app.R^2)+A3*(app.R^3);
                        if f>app.R
                            f = f+0;
                        elseif f<=app.R
                            f = app.R;
                        end
                    end
                    %% Threshold Range
                    t = 20;
                    Sig_max = (app.Applied_Stress+app.Mean_Stress)/(10^6);
                    Sig_min = (app.Mean_Stress-app.Applied_Stress)/(10^6);
                    Y = Crack_Shape(app.CG,app.ai);
                    Kmax = MaxSIF(Sig_max,Y,app.ai);
                    DelK = SIFRange(Kmax,app.R);
                    t0 = 2.5*((app.K1c/app.YS)^2);
                    app.Kcrit = app.K1c*(1+app.Bk*exp(-(app.Ak*t/t0)));
                    DelKth = Threshold(app.ai,app.DK0,f,A0,app.R,app.Cth_p);
                    Alpha_g = 1.15; % For Plane Stress
                    StepSize = 1*10^-2;% Step Size for crack growth
                    N_growth = 0;
                    Ana = zeros([1 100000]);
                    Livi = zeros([1 100000]);
                    for i = 1:100000
                        dN_da = ((1-Kmax/app.Kcrit)^app.p)/(app.C*((((1-f)/(1-app.R))*(DelK))^app.n)*((1-DelKth/DelK)^app.q));
                        Ana(i) = app.ai;
                        app.ai = app.ai+StepSize;
                        N_growth = N_growth+StepSize*dN_da;
                        Y = Crack_Shape(app.CG,app.ai);
                        Kmax = MaxSIF(Sig_max,Y,app.ai);
                        DelK = SIFRange(Kmax,app.R);
                        DelKth = Threshold(app.ai,app.DK0,f,A0,app.R,app.Cth_p);
                        phi = Calc_phi(DelKth,DelK,app.RSO);
                        ZOL = Calc_ZOL(Kmax,Alpha_g,app.YS);
                        if i>=app.i_OL
                            if i==app.i_OL
                            a_OL = Calc_OL_size(app.ai);
                            end
                            Kmin = MinSIF(Sig_min,Y,app.ai);
                            K_max_OL = app.K_overload_percen*Kmax;
                            del_a = Calc_dela(a_OL,app.ai);
                            if del_a<=ZOL
                                K_R_W = Calc_Krw(K_max_OL,del_a,ZOL,Kmax);
                                K_R = phi*K_R_W;
                                Kmax_eff = Kmax-K_R;
                                Kmin_eff = Kmin-K_R;
                                if Kmin<=0
                                    Kmin_eff=0; % From Comparative Study Paper
                                end
                                if app.K_overload_percen>=app.RSO
                                    Kmax_eff = DelKth/(1-app.R);
                                end
                                R_eff = Kmin_eff/Kmax_eff;
                                app.R = R_eff;
                                DelK_eff = Kmax_eff-Kmin_eff;
                                DelK=DelK_eff;
                                Kmax = Kmax_eff;
                            end
                        end 
                        Livi(i) = N_growth;
                        if Kmax>=app.Kcrit
                            break
                        end
                    end
                    app.ac = (1/pi)*((app.Kcrit/(Y*Sig_max))^2);
                    app.CriticalSizeEditField.Value = app.ac;
                    app.size = Ana;
                    app.growth_life = Livi;
                    app.Ng = N_growth; 
             end
        end

        % Value changed function: OverloadSIFSIFEditField
        function OverloadSIFSIFEditFieldValueChanged(app, event)
            value = app.OverloadSIFSIFEditField.Value;
            app.K_overload_percen = value;
        end

        % Value changed function: OverloadCycleEditField
        function OverloadCycleEditFieldValueChanged(app, event)
            value = app.OverloadCycleEditField.Value;
            app.i_OL=value;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            % Stress Life
            app.MaterialDropDown.Value = 'Pick';
            app.Corrected_Stress = 0;
            app.StressEditField.Value = app.Corrected_Stress;
            app.R = -5;
            app.StressRatioREditField.Value = app.R;
            app.Applied_Stress = 0;
            app.AppliedStressofyieldEditField.Value = app.Applied_Stress;
            app.MeanStressCorrectionCriteriaDropDown.Value = 'Pick';
            cla(app.UIAxes);
            app.SelectGeometryDropDown.Value = 'Pick';
            app.N_failure = 0;
            app.LifeEditField.Value = app.N_failure;
            app.StressLevel.Value = {''};
            app.CumulativeDamageTWISTEditField.Value = 0;
            app.RepetitionsEditField.Value = 0;
            
            % Crack Growth
            app.CrackGeometryDropDown.Value = 'Pick';
            app.K_overload_percen = 0;
            app.OverloadSIFSIFEditField.Value = app.K_overload_percen;
            app.i_OL = 0;
            app.OverloadCycleEditField.Value = app.i_OL;
            app.Ng = 0;
            app.GrowthLifeEditField_2.Value = app.Ng;
            cla(app.UIAxes2)
            app.Kcrit = 0;
            app.EditField_4.Value = app.Kcrit;
            app.MethodDropDown.Value = 'Pick';
            app.growth_life = 0;
            app.size = 0;
            app.CriticalSizeEditField.Value = 0;
            
            % Strain Life
            app.SelectMaterialDropDown.Value = 'Pick';
            app.R_2 = -5;
            app.StressRatioREditField_2.Value = app.R_2;
            app.Applied_Stress_2 = 0;
            app.AppliedStressofyieldEditField_2.Value = app.Applied_Stress_2;
            app.Decimal = 0;
            app.NumberofDecimalPlacesEditField.Value = app.Decimal;
            app.SelectGeometryDropDown_2.Value = 'Pick';
            app.Mean_Correction = 'Pick';
            app.MeanCorrectionDropDown.Value = app.Mean_Correction;
            app.ILG = 0;
            app.IRG = 0;
            app.InitialLifeGuessEditField.Value = app.ILG;
            app.Reversals = 0;
            app.Corrected_Stress_2 = 0;
            app.Corrected_Strain = 0;
            app.MaximumStressEditField.Value = app.Corrected_Stress_2;
            app.StrainAmplitudeEditField.Value = app.Corrected_Strain;
            app.TransitionReversalsEditField.Value = 0;
            app.ReversalsEditField.Value = 0;
            app.CyclesEditField.Value = 0;
            cla(app.UIAxes3);
            app.TextArea.Value = {''};
            app.Damage_Strain = 0;
            app.CumulativeDamageTWISTEditField_2.Value = app.Damage_Strain;
            app.N_rep_strain = 0;
            app.RepetitionsEditField_2.Value = app.N_rep_strain;
            app.CyclestoFailureEditField_2.Value = app.N_rep_strain;
        end

        % Button pushed function: DamageButton
        function DamageButtonPushed(app, event)
%               Stress_Level_Flight = [1.6,1.5,1.3,1.15,0.995,0.840,0.685,0.530,0.375,0.222];
            Stress_Level_Flight = [0.375,0.222];
%             Mean_Stress_Flight_level = [1 1 1 1 1 1 1 1 1 1];
            Mean_Stress_Flight_level = [1 1];
%             Cycles_Flight = [1 2 5 18 52 152 800 4170 34800 358665];
            Cycles_Flight = [34800 358665];
%             Stress_Level_Ground = [1.55 1.5 1.4 1.3250 1.2475 1.17 1.0150 0.9375 0.8610];
%             Mean_Stress_Level_Ground = [1.05 1 0.9 0.8250 0.7475 0.6700 0.5150 0.4375 0.3610];
%             Stress_Amp_Level_Ground = Stress_Level_Ground-Mean_Stress_Level_Ground;
%             Cycles_Ground = [1 1 3 9 24 60 420 1090 2211];
            Stresses = [Stress_Level_Flight];
            Cycles = [Cycles_Flight];
            Stress_acting = app.Applied_Stress.*Stresses;
            Mean_1 = app.Applied_Stress.*Mean_Stress_Flight_level;
%             Mean_2 = app.Applied_Stress.*Mean_Stress_Level_Ground;
            Mean = [Mean_1];
            C_Stress = Mean_Stress_Correction(app.MeanStressCorrectionCriteriaDropDown.Value,Mean,Stress_acting,app.Ultimate_Strength,app.Yield_Strength);
            switch app.SelectGeometryDropDown.Value
                case 'Flat Plate'
                    app.K = 1.0;
                    C_Stress = app.K.*C_Stress;
                case 'Circular Hole'
                    a = 100;% mm diameter of hole
                    d = 500;% mm width of plate
                    app.K = 3*d/(a+d);
                    C_Stress = app.K.*C_Stress;
                case 'Elliptical Hole'
                    a = 4;% mm semi major axis
                    b = 3;% mm semi minor axis
                    app.K = 1 + 2*(a/b);
                    C_Stress = app.K.*C_Stress;
                case 'Edge Notch'
                    h = 0.5;% mm notch height
                    r = 0.3;% mm radius of notch
                    app.K = 1+2*sqrt(h/r);
                    C_Stress = app.K.*C_Stress;
            end
            B = -(1/3).*log10((0.75.*app.Ultimate_Strength)./app.FS);
            A = ((0.75.*app.Ultimate_Strength)^2)/app.FS;
            Failure_Cycles = (C_Stress./A).^(1./B);
            Damage_vector = Cycles./Failure_Cycles;
            app.Damage = sum(Damage_vector);
            app.CumulativeDamageTWISTEditField.Value = app.Damage;
            app.N_rep = 1/app.Damage;
            app.RepetitionsEditField.Value = app.N_rep;
            Cycles_to_Failure = app.N_rep.*sum(Cycles);
            app.CyclestoFailureEditField.Value = Cycles_to_Failure;
        end

        % Value changed function: SelectMaterialDropDown
        function SelectMaterialDropDownValueChanged(app, event)
            value = app.SelectMaterialDropDown.Value;
            switch value
                case 'Al-2024-T3'
                app.Density_2 = 2780;    % Density in kg/m3
                app.Ultimate_Strength_2 = 483*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength_2 = 345*10^6; % Tensile Yield Strength in Pa
                app.Yield_Strength_prime = 427*10^6; % Tensile Yield Strength cyclic in Pa
                app.E_2 = 70*10^9;      % Modulus of Elasticity in Pa
                app.Poisson_2 = 0.33;        % Poisson Ratio
                % Strain Life Parameters at 24% Reduction in Area
                app.K_prime = 655*10^6; % Cyclic Strength Coefficient in Pa
                app.n_prime = 0.065; % Cyclic Strain Hardening Exponent
                app.sigma_f_prime = 1100*10^6; % Cyclic Fatigue Strength Coefficient in Pa
                app.epsilon_f_prime = 0.22; % Cyclic Fatigue Ductility Coefficient
                app.bbb = -0.124; % Fatigue Strength Exponent
                app.ccc = -0.59; % Fatigue Ductility Exponent
                
                case 'Steel-1045'
                app.Density_2 = 7870;    % Density in kg/m3
                app.Ultimate_Strength_2 = 585*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength_2 = 450*10^6; % Tensile Yield Strength in Pa
                app.E_2 = 206*10^9;      % Modulus of Elasticity in Pa
                app.Poisson_2 = 0.29;        % Poisson Ratio
                % Strain Life Parameters at 44% Reduction in Area
                app.K_prime = 1022*10^6; % Cyclic Strength Coefficient in Pa
                app.n_prime = 0.152; % Cyclic Strain Hardening Exponent
                app.sigma_f_prime = 916*10^6; % Cyclic Fatigue Strength Coefficient in Pa
                app.epsilon_f_prime = 0.486; % Cyclic Fatigue Ductility Coefficient
                app.bbb = -0.079; % Fatigue Strength Exponent
                app.ccc = -0.520; % Fatigue Ductility Exponent
                
                case 'Al-7075-T6'
                app.Density_2 = 2810;    % Density in kg/m3
                app.Ultimate_Strength_2 = 510*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength_2 = 434*10^6; % Tensile Yield Strength in Pa
                app.Yield_Strength_prime = 524*10^6; % Tensile Yield Strength cyclic in Pa
                app.E_2 = 71.7*10^9;      % Modulus of Elasticity in Pa
                app.Poisson_2 = 0.33;        % Poisson Ratio
                % Strain Life Parameters at 34% Reduction in Area
                app.K_prime = 1675.8*10^6; % Cyclic Strength Coefficient in Pa using relation K_prime = Sigma_f_prime/(epsilon_f_prime)^n_prime
                app.n_prime = 0.146; % Cyclic Strain Hardening Exponent
                app.sigma_f_prime = 1315*10^6; % Cyclic Fatigue Strength Coefficient in Pa
                app.epsilon_f_prime = 0.19; % Cyclic Fatigue Ductility Coefficient
                app.bbb = -0.126; % Fatigue Strength Exponent
                app.ccc = -0.52; % Fatigue Ductility Exponent
                
                case 'Ti-6Al-4V'
                app.Density_2 = 4430;    % Density in kg/m3
                app.Ultimate_Strength_2 = 860*10^6;   % Tensile Ultimate Strength in Pa
                app.Yield_Strength_2 = 790*10^6; % Tensile Yield Strength in Pa
                app.E_2 = 113.8*10^9;      % Modulus of Elasticity in Pa
                app.Poisson_2 = 0.342;        % Poisson Ratio
                % Strain Life Parameters at 22% Reduction in Area from
                % asm-metals-handbooks
                app.K_prime = 1469.5*10^6; % Cyclic Strength Coefficient in Pa using relation K_prime = Sigma_f_prime/(epsilon_f_prime)^n_prime
                app.n_prime = 0.095; % Cyclic Strain Hardening Exponent
                app.sigma_f_prime = 1293*10^6; % Cyclic Fatigue Strength Coefficient in Pa
                app.epsilon_f_prime = 0.260; % Cyclic Fatigue Ductility Coefficient
                app.bbb = -0.088; % Fatigue Strength Exponent
                app.ccc = -0.721; % Fatigue Ductility Exponent
            end
        end

        % Value changed function: StressRatioREditField_2
        function StressRatioREditField_2ValueChanged(app, event)
            app.R_2 = app.StressRatioREditField_2.Value;
        end

        % Value changed function: AppliedStressofyieldEditField_2
        function AppliedStressofyieldEditField_2ValueChanged(app, event)
            value = app.AppliedStressofyieldEditField_2.Value;
            app.Applied_Stress_2 = value*app.Yield_Strength_2;
            A = (1-app.R_2)/(1+app.R_2);
            app.Mean_Stress_2 = app.Applied_Stress_2/A;
            app.Max_Stress = app.Mean_Stress_2+app.Applied_Stress_2;
        end

        % Value changed function: SelectGeometryDropDown_2
        function SelectGeometryDropDown_2ValueChanged(app, event)
            value = app.SelectGeometryDropDown_2.Value;
            switch value
                case 'Flat Plate'
                    app.K_f = 1.0;
                    app.Corrected_Stress_2 = app.K_f*app.Applied_Stress_2;
                    app.MaximumStressEditField.Value = app.Max_Stress;
%                     if app.Max_Stress<(app.Yield_Strength_2)
%                       app.Corrected_Strain = Calculate_Strain_Less(app.Corrected_Stress_2,app.E_2);
%                     elseif app.Max_Stress>(app.Yield_Strength_2)
%                      app.Corrected_Strain = Calculate_Strain(app.Corrected_Stress_2,app.E_2,app.K_prime,app.n_prime);   
%                     end
                    app.Corrected_Strain = Calculate_Strain(app.Corrected_Stress_2,app.E_2,app.K_prime,app.n_prime);
                    app.StrainAmplitudeEditField.Value = app.Corrected_Strain;
                case 'Circular Hole'
                    a = 100;% mm diameter of hole
                    d = 500;% mm width of plate
                    app.K_f = 3*d/(a+d);
                    app.Corrected_Stress_2 = Newton_Raphson_Stress(app.K_f,app.Applied_Stress_2,app.E_2,app.K_prime,app.n_prime,app.Decimal,app.Applied_Stress_2);
                    app.Corrected_Stress_2 = double(app.Corrected_Stress_2);
                    app.MaximumStressEditField.Value = (app.Corrected_Stress_2+app.Mean_Stress_2);
                    app.Corrected_Strain = Calculate_Strain(app.Corrected_Stress_2,app.E_2,app.K_prime,app.n_prime);
                    app.StrainAmplitudeEditField.Value = app.Corrected_Strain;
                case 'Elliptical Hole'
                    a = 600;% mm semi major axis
                    b = 300;% mm semi minor axis
                    app.K_f = 1 + 2*(a/b);
                    app.Corrected_Stress_2 = Newton_Raphson_Stress(app.K_f,app.Applied_Stress_2,app.E_2,app.K_prime,app.n_prime,app.Decimal,app.Applied_Stress_2);
                    app.Corrected_Stress_2 = double(app.Corrected_Stress_2);
                    app.MaximumStressEditField.Value = (app.Corrected_Stress_2+app.Mean_Stress_2);
                    app.Corrected_Strain = Calculate_Strain(app.Corrected_Stress_2,app.E_2,app.K_prime,app.n_prime);
                    app.StrainAmplitudeEditField.Value = app.Corrected_Strain;
                case 'Edge Notch'
                    h = 0.5;% mm notch height
                    r = 0.3;% mm radius of notch
                    app.K_f = 1+2*sqrt(h/r);
                    app.Corrected_Stress_2 = Newton_Raphson_Stress(app.K_f,app.Applied_Stress_2,app.E_2,app.K_prime,app.n_prime,app.Decimal,app.Applied_Stress_2);
                    app.Corrected_Stress_2 = double(app.Corrected_Stress_2);
                    app.MaximumStressEditField.Value = (app.Corrected_Stress_2+app.Mean_Stress_2);
                    app.Corrected_Strain = Calculate_Strain(app.Corrected_Stress_2,app.E_2,app.K_prime,app.n_prime);
                    app.StrainAmplitudeEditField.Value = app.Corrected_Strain;
            end
        end

        % Value changed function: NumberofDecimalPlacesEditField
        function NumberofDecimalPlacesEditFieldValueChanged(app, event)
            app.Decimal = app.NumberofDecimalPlacesEditField.Value;
        end

        % Value changed function: MeanCorrectionDropDown
        function MeanCorrectionDropDownValueChanged(app, event)
            app.Mean_Correction = app.MeanCorrectionDropDown.Value;
            app.TextArea.Value = {'As Life calculation is based on a Newtons Method, Enter the initial life guess'};
        end

        % Button pushed function: ComputeButton_2
        function ComputeButton_2Pushed(app, event)
            if app.R_2 == -1
                app.Reversals = Newton_Raphson_Life(app.sigma_f_prime,app.epsilon_f_prime,app.bbb,app.ccc,app.E_2,app.Corrected_Strain,app.Decimal,app.IRG);
                app.Reversals = double(app.Reversals);
                app.ReversalsEditField.Value = app.Reversals;
                Cycles = app.Reversals/2;
                app.CyclesEditField.Value = Cycles;
                Transition_Reversals = Transition(app.epsilon_f_prime,app.E_2,app.sigma_f_prime,app.bbb,app.ccc);
                app.TransitionReversalsEditField.Value = Transition_Reversals;
            elseif app.R_2 ~= -1
                switch app.Mean_Correction
                    case 'Morrow'
                    app.Reversals = Newton_Raphson_Morrow(app.sigma_f_prime,app.epsilon_f_prime,app.bbb,app.ccc,app.E_2,app.Corrected_Strain,app.Mean_Stress_2,app.Decimal,app.IRG);
                    app.Reversals = double(app.Reversals);
                    app.ReversalsEditField.Value = app.Reversals;
                    Cycles = app.Reversals/2;
                    app.CyclesEditField.Value = Cycles;
                    Transition_Reversals = Transition(app.epsilon_f_prime,app.E_2,app.sigma_f_prime,app.bbb,app.ccc);
                    app.TransitionReversalsEditField.Value = Transition_Reversals;
                    case 'SWT'
                    app.Reversals = Newton_Raphson_SWT(app.sigma_f_prime,app.epsilon_f_prime,app.bbb,app.ccc,app.E_2,app.Corrected_Strain,app.Max_Stress,app.Decimal,app.IRG);
                    app.Reversals = double(app.Reversals);
                    app.ReversalsEditField.Value = app.Reversals;
                    Cycles = app.Reversals/2;
                    app.CyclesEditField.Value = Cycles; 
                    Transition_Reversals = Transition(app.epsilon_f_prime,app.E_2,app.sigma_f_prime,app.bbb,app.ccc);
                    app.TransitionReversalsEditField.Value = Transition_Reversals;
                end
            end
            Reversal_vector = linspace(app.Reversals/1000,app.Reversals,50);
            E_Strain = Elastic_Strain(app.sigma_f_prime,app.E_2,Reversal_vector,app.bbb);
            xxx_e = app.Reversals/1000:10:app.Reversals;
            yyy_e = interpn(Reversal_vector,E_Strain,xxx_e,'spline');
            P_Strain = Plastic_Strain(app.epsilon_f_prime,Reversal_vector,app.ccc);
            xxx_p = app.Reversals/1000:10:app.Reversals;
            yyy_p = interpn(Reversal_vector,P_Strain,xxx_p,'spline');
            T_Strain = E_Strain + P_Strain;
            xxx_t = app.Reversals/1000:10:app.Reversals;
            yyy_t = interpn(Reversal_vector,T_Strain,xxx_t,'spline');
            loglog(app.UIAxes3,xxx_e,yyy_e,'g',xxx_p,yyy_p,'r',xxx_t,yyy_t,'k','lineWidth',1)
%             hold on
%             semilogx(app.UIAxes3,Reversal_vector,P_Strain,'-o',xxx_p,yyy_p,'r','lineWidth',1)
%             hold on
%             loglog(app.UIAxes3,Reversal_vector,T_Strain,'-o',xxx_t,yyy_t,'k','lineWidth',1)
            legend(app.UIAxes3,'Elastic', 'Plastic', 'Total')
            grid(app.UIAxes3,"minor")
        end

        % Value changed function: InitialLifeGuessEditField
        function InitialLifeGuessEditFieldValueChanged(app, event)
            app.ILG = app.InitialLifeGuessEditField.Value;
            app.IRG = 2*app.ILG;
        end

        % Button pushed function: ComputeButton_3
        function ComputeButton_3Pushed(app, event)
            Stress_Level_Flight = [1.6,1.5,1.3,1.15,0.995,0.840,0.685,0.530,0.375,0.222];
%             Stress_Level_Flight = [0.375,0.222];
            Mean_Stress_Flight_level = [1 1 1 1 1 1 1 1 1 1];
%             Mean_Stress_Flight_level = [1 1];
            Cycles_Flight = [1 2 5 18 52 152 800 4170 34800 358665];
%             Cycles_Flight = [34800 358665];
%             Stress_Level_Ground = [1.55 1.5 1.4 1.3250 1.2475 1.17 1.0150 0.9375 0.8610];
%             Mean_Stress_Level_Ground = [1.05 1 0.9 0.8250 0.7475 0.6700 0.5150 0.4375 0.3610];
%             Stress_Amp_Level_Ground = Stress_Level_Ground-Mean_Stress_Level_Ground;
%             Cycles_Ground = [1 1 3 9 24 60 420 1090 2211];
            Stresses = [Stress_Level_Flight];
            Cycles_applied = [Cycles_Flight];
            Stress_acting = app.Applied_Stress_2.*Stresses;
            Mean_1 = app.Applied_Stress_2.*Mean_Stress_Flight_level;
%             Mean_2 = app.Applied_Stress.*Mean_Stress_Level_Ground;
            Mean = [Mean_1];
            Max = Mean+Stress_acting;
            Cycles = zeros([1,length(Mean)]);
            Corrected_S = zeros([1,length(Mean)]);
            Corrected_St = zeros([1,length(Mean)]);
            for i=1:length(Mean)
             [Corrected_S([1,i]),Corrected_St([1,i])] = K_f_Strain(app.SelectGeometryDropDown_2.Value,Stress_acting(i),app.E_2,app.K_prime,app.n_prime,app.Decimal,app.Applied_Stress_2);
             Cycles([1,i]) = Cycles_Strain(app.R_2,app.Mean_Correction,app.sigma_f_prime,app.epsilon_f_prime,app.bbb,app.ccc,app.E_2,Corrected_St([1,i]),app.Decimal,app.IRG,Mean(i),Max(i));
             end
            Damage_vector = Cycles_applied./Cycles;
            app.Damage_Strain = sum(Damage_vector);
            app.Damage_Strain = double(app.Damage_Strain);
            app.CumulativeDamageTWISTEditField_2.Value = app.Damage_Strain;
            app.N_rep_strain = 1/app.Damage_Strain;
            app.RepetitionsEditField_2.Value = app.N_rep_strain;
            Cycles_to_Failure = app.N_rep_strain.*sum(Cycles_applied);
            app.CyclestoFailureEditField_2.Value = Cycles_to_Failure;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StressLifeTab = uitab(app.TabGroup);
            app.StressLifeTab.Title = 'Stress Life';
            app.UIAxes = uiaxes(app.StressLifeTab);
            title(app.UIAxes, 'SN Curve')
            xlabel(app.UIAxes, 'Cycles to Failure (Log)')
            ylabel(app.UIAxes, 'Stress (Pa)')
            app.InputsPanel = uipanel(app.StressLifeTab);
            app.InputsPanel.Title = 'Inputs';
            app.MaterialDropDownLabel = uilabel(app.InputsPanel);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.InputsPanel);
            app.MaterialDropDown.Items = {'Pick', 'Al-2024-T6', 'Al-7075-T6', 'Al-7075-T73', 'Ti-6Al-4V', 'Steel-17-4Ph'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged, true);
            app.MaterialDropDown.Value = 'Pick';
            app.AppliedStressofyieldEditFieldLabel = uilabel(app.InputsPanel);
            app.AppliedStressofyieldEditFieldLabel.Text = 'Applied Stress (% of yield)';
            app.AppliedStressofyieldEditField = uieditfield(app.InputsPanel, 'numeric');
            app.AppliedStressofyieldEditField.ValueChangedFcn = createCallbackFcn(app, @AppliedStressofyieldEditFieldValueChanged, true);
            app.StressRatioREditFieldLabel = uilabel(app.InputsPanel);
            app.StressRatioREditFieldLabel.Text = 'Stress Ratio R';
            app.StressRatioREditField = uieditfield(app.InputsPanel, 'numeric');
            app.StressRatioREditField.ValueChangedFcn = createCallbackFcn(app, @StressRatioREditFieldValueChanged, true);
            app.StressRatioREditField.Value = -5;
            app.EnduranceCorrectionButton = uibutton(app.InputsPanel, 'push');
            app.EnduranceCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @EnduranceCorrectionButtonPushed, true);
            app.EnduranceCorrectionButton.Text = 'Endurance Correction';
            app.MeanStressCorrectionCriteriaDropDownLabel = uilabel(app.InputsPanel);
            app.MeanStressCorrectionCriteriaDropDownLabel.Text = 'Mean Stress Correction Criteria';
            app.MeanStressCorrectionCriteriaDropDown = uidropdown(app.InputsPanel);
            app.MeanStressCorrectionCriteriaDropDown.Items = {'Pick', 'Soderberg', 'Goodman', 'Elliptical', 'Gerber'};
            app.MeanStressCorrectionCriteriaDropDown.ValueChangedFcn = createCallbackFcn(app, @MeanStressCorrectionCriteriaDropDownValueChanged, true);
            app.MeanStressCorrectionCriteriaDropDown.Value = 'Pick';
            app.StressEditFieldLabel = uilabel(app.InputsPanel);
            app.StressEditFieldLabel.Text = 'Stress';
            app.StressEditField = uieditfield(app.InputsPanel, 'numeric');
            app.SelectGeometryDropDownLabel = uilabel(app.InputsPanel);
            app.SelectGeometryDropDownLabel.Text = 'Select Geometry';
            app.SelectGeometryDropDown = uidropdown(app.InputsPanel);
            app.SelectGeometryDropDown.Items = {'Pick', 'Flat Plate', 'Circular Hole', 'Elliptical Hole', 'Edge Notch'};
            app.SelectGeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectGeometryDropDownValueChanged, true);
            app.SelectGeometryDropDown.Value = 'Pick';
            app.PaLabel = uilabel(app.InputsPanel);
            app.PaLabel.Text = 'Pa';
            app.StressLevel = uitextarea(app.InputsPanel);
            app.STRESSLIFETOTALLIFECALCULATIONLabel = uilabel(app.StressLifeTab);
            app.STRESSLIFETOTALLIFECALCULATIONLabel.Text = 'STRESS LIFE TOTAL LIFE CALCULATION';
            app.CalculateLifeButton = uibutton(app.StressLifeTab, 'push');
            app.CalculateLifeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateLifeButtonPushed, true);
            app.CalculateLifeButton.Text = 'Calculate Life';
            app.LifeEditFieldLabel = uilabel(app.StressLifeTab);
            app.LifeEditFieldLabel.Text = 'Life';
            app.LifeEditField = uieditfield(app.StressLifeTab, 'numeric');
            app.CyclesLabel = uilabel(app.StressLifeTab);
            app.CyclesLabel.Text = 'Cycles';
            app.DamageButton = uibutton(app.StressLifeTab, 'push');
            app.DamageButton.ButtonPushedFcn = createCallbackFcn(app, @DamageButtonPushed, true);
            app.DamageButton.Text = 'Damage';
            app.CumulativeDamageTWISTEditFieldLabel = uilabel(app.StressLifeTab);
            app.CumulativeDamageTWISTEditFieldLabel.Text = {'Cumulative Damage'; '(TWIST)'};
            app.CumulativeDamageTWISTEditField = uieditfield(app.StressLifeTab, 'numeric');
            app.RepetitionsEditFieldLabel = uilabel(app.StressLifeTab);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.StressLifeTab, 'numeric');
            app.BlocksLabel = uilabel(app.StressLifeTab);
            app.BlocksLabel.Text = 'Blocks';
            app.CyclestoFailureEditFieldLabel = uilabel(app.StressLifeTab);
            app.CyclestoFailureEditFieldLabel.Text = 'Cycles to Failure';
            app.CyclestoFailureEditField = uieditfield(app.StressLifeTab, 'numeric');
            app.CyclesLabel_3 = uilabel(app.StressLifeTab);
            app.CyclesLabel_3.Text = 'Cycles';
            app.CrackGrowthTab = uitab(app.TabGroup);
            app.CrackGrowthTab.Title = 'Crack Growth';
            app.UIAxes2 = uiaxes(app.CrackGrowthTab);
            title(app.UIAxes2, 'Crack Growth')
            xlabel(app.UIAxes2, 'N (cycles)')
            ylabel(app.UIAxes2, 'Crack Size (mm)')
            zlabel(app.UIAxes2, 'Z')
            app.ComputeButton = uibutton(app.CrackGrowthTab, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.GrowthLifeEditField_2Label = uilabel(app.CrackGrowthTab);
            app.GrowthLifeEditField_2Label.Text = 'Growth Life';
            app.EditField_4 = uieditfield(app.CrackGrowthTab, 'numeric');
            app.GrowthLifeEditField_2 = uieditfield(app.CrackGrowthTab, 'numeric');
            app.CriticalSIFLabel = uilabel(app.CrackGrowthTab);
            app.CriticalSIFLabel.Text = 'Critical SIF';
            app.MPasqrtmmLabel = uilabel(app.CrackGrowthTab);
            app.MPasqrtmmLabel.Text = 'MPa.sqrt(mm)';
            app.MethodDropDownLabel = uilabel(app.CrackGrowthTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.CrackGrowthTab);
            app.MethodDropDown.Items = {'Pick', 'Paris Law', 'No Interaction', 'Overload Interaction'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Pick';
            app.CyclesLabel_2 = uilabel(app.CrackGrowthTab);
            app.CyclesLabel_2.Text = 'Cycles';
            app.CrackGeometryDropDownLabel = uilabel(app.CrackGrowthTab);
            app.CrackGeometryDropDownLabel.Text = 'Crack Geometry';
            app.CrackGeometryDropDown = uidropdown(app.CrackGrowthTab);
            app.CrackGeometryDropDown.Items = {'Pick', 'Edge Crack', 'Center Crack', 'Center Hole'};
            app.CrackGeometryDropDown.ValueChangedFcn = createCallbackFcn(app, @CrackGeometryDropDownValueChanged, true);
            app.CrackGeometryDropDown.Value = 'Pick';
            app.OLInteractionInputsPanel = uipanel(app.CrackGrowthTab);
            app.OLInteractionInputsPanel.Title = 'OL Interaction Inputs';
            app.OverloadSIFSIFEditFieldLabel = uilabel(app.OLInteractionInputsPanel);
            app.OverloadSIFSIFEditFieldLabel.Text = '(Overload SIF)/SIF';
            app.OverloadSIFSIFEditField = uieditfield(app.OLInteractionInputsPanel, 'numeric');
            app.OverloadSIFSIFEditField.ValueChangedFcn = createCallbackFcn(app, @OverloadSIFSIFEditFieldValueChanged, true);
            app.OverloadCycleEditFieldLabel = uilabel(app.OLInteractionInputsPanel);
            app.OverloadCycleEditFieldLabel.Text = 'Overload Cycle';
            app.OverloadCycleEditField = uieditfield(app.OLInteractionInputsPanel, 'numeric');
            app.OverloadCycleEditField.ValueChangedFcn = createCallbackFcn(app, @OverloadCycleEditFieldValueChanged, true);
            app.AlandTi35SteelLabel = uilabel(app.OLInteractionInputsPanel);
            app.AlandTi35SteelLabel.Text = '<2.3 Al and Ti, <3.5 Steel';
            app.IncaseofOverloadInteractionputinputsfirstLabel = uilabel(app.CrackGrowthTab);
            app.IncaseofOverloadInteractionputinputsfirstLabel.Text = 'In case of Overload Interaction put inputs first';
            app.CRACKGROWTHLabel = uilabel(app.CrackGrowthTab);
            app.CRACKGROWTHLabel.Text = 'CRACK GROWTH ';
            app.CriticalSizeEditFieldLabel = uilabel(app.CrackGrowthTab);
            app.CriticalSizeEditFieldLabel.Text = 'Critical Size';
            app.CriticalSizeEditField = uieditfield(app.CrackGrowthTab, 'numeric');
            app.mmLabel = uilabel(app.CrackGrowthTab);
            app.mmLabel.Text = 'mm';
            app.StrainLifeTab = uitab(app.TabGroup);
            app.StrainLifeTab.Title = 'Strain Life';
            app.UIAxes3 = uiaxes(app.StrainLifeTab);
            title(app.UIAxes3, 'Strain-Life Curve')
            xlabel(app.UIAxes3, 'Reversals (2N_{f})  (Log)')
            ylabel(app.UIAxes3, 'Strain Amplitude (Log)')
            zlabel(app.UIAxes3, 'Z')
            app.InputsPanel_2 = uipanel(app.StrainLifeTab);
            app.InputsPanel_2.Title = 'Inputs';
            app.SelectMaterialDropDownLabel = uilabel(app.InputsPanel_2);
            app.SelectMaterialDropDownLabel.Text = 'Select Material';
            app.SelectMaterialDropDown = uidropdown(app.InputsPanel_2);
            app.SelectMaterialDropDown.Items = {'Pick', 'Al-2024-T3', 'Steel-1045', 'Al-7075-T6', 'Ti-6Al-4V'};
            app.SelectMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMaterialDropDownValueChanged, true);
            app.SelectMaterialDropDown.Value = 'Pick';
            app.StressRatioREditField_2Label = uilabel(app.InputsPanel_2);
            app.StressRatioREditField_2Label.Text = 'Stress Ratio R';
            app.StressRatioREditField_2 = uieditfield(app.InputsPanel_2, 'numeric');
            app.StressRatioREditField_2.ValueChangedFcn = createCallbackFcn(app, @StressRatioREditField_2ValueChanged, true);
            app.StressRatioREditField_2.Value = -5;
            app.AppliedStressofyieldEditField_2Label = uilabel(app.InputsPanel_2);
            app.AppliedStressofyieldEditField_2Label.Text = 'Applied Stress (% of yield)';
            app.AppliedStressofyieldEditField_2 = uieditfield(app.InputsPanel_2, 'numeric');
            app.AppliedStressofyieldEditField_2.ValueChangedFcn = createCallbackFcn(app, @AppliedStressofyieldEditField_2ValueChanged, true);
            app.SelectGeometryDropDown_2Label = uilabel(app.InputsPanel_2);
            app.SelectGeometryDropDown_2Label.Text = 'Select Geometry';
            app.SelectGeometryDropDown_2 = uidropdown(app.InputsPanel_2);
            app.SelectGeometryDropDown_2.Items = {'Pick', 'Flat Plate', 'Circular Hole', 'Elliptical Hole', 'Edge Notch'};
            app.SelectGeometryDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectGeometryDropDown_2ValueChanged, true);
            app.SelectGeometryDropDown_2.Value = 'Pick';
            app.NumberofDecimalPlacesEditFieldLabel = uilabel(app.InputsPanel_2);
            app.NumberofDecimalPlacesEditFieldLabel.Text = 'Number of Decimal Places';
            app.NumberofDecimalPlacesEditField = uieditfield(app.InputsPanel_2, 'numeric');
            app.NumberofDecimalPlacesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofDecimalPlacesEditFieldValueChanged, true);
            app.MeanCorrectionDropDownLabel = uilabel(app.InputsPanel_2);
            app.MeanCorrectionDropDownLabel.Text = 'Mean Correction';
            app.MeanCorrectionDropDown = uidropdown(app.InputsPanel_2);
            app.MeanCorrectionDropDown.Items = {'Pick', 'Morrow', 'SWT'};
            app.MeanCorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MeanCorrectionDropDownValueChanged, true);
            app.MeanCorrectionDropDown.Value = 'Pick';
            app.TextArea = uitextarea(app.InputsPanel_2);
            app.InitialLifeGuessEditFieldLabel = uilabel(app.InputsPanel_2);
            app.InitialLifeGuessEditFieldLabel.Text = 'Initial Life Guess';
            app.InitialLifeGuessEditField = uieditfield(app.InputsPanel_2, 'numeric');
            app.InitialLifeGuessEditField.ValueChangedFcn = createCallbackFcn(app, @InitialLifeGuessEditFieldValueChanged, true);
            app.STRAINLIFELIFECALCULATIONLabel = uilabel(app.StrainLifeTab);
            app.STRAINLIFELIFECALCULATIONLabel.Text = 'STRAIN-LIFE LIFE CALCULATION';
            app.MaximumStressEditFieldLabel = uilabel(app.StrainLifeTab);
            app.MaximumStressEditFieldLabel.Text = 'Maximum Stress';
            app.MaximumStressEditField = uieditfield(app.StrainLifeTab, 'numeric');
            app.PaLabel_2 = uilabel(app.StrainLifeTab);
            app.PaLabel_2.Text = 'Pa';
            app.StrainAmplitudeEditFieldLabel = uilabel(app.StrainLifeTab);
            app.StrainAmplitudeEditFieldLabel.Text = 'Strain Amplitude';
            app.StrainAmplitudeEditField = uieditfield(app.StrainLifeTab, 'numeric');
            app.ReversalsEditFieldLabel = uilabel(app.StrainLifeTab);
            app.ReversalsEditFieldLabel.Text = 'Reversals';
            app.ReversalsEditField = uieditfield(app.StrainLifeTab, 'numeric');
            app.CyclesEditFieldLabel = uilabel(app.StrainLifeTab);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.StrainLifeTab, 'numeric');
            app.ComputeButton_2 = uibutton(app.StrainLifeTab, 'push');
            app.ComputeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_2Pushed, true);
            app.ComputeButton_2.Text = 'Compute';
            app.TransitionReversalsEditFieldLabel = uilabel(app.StrainLifeTab);
            app.TransitionReversalsEditFieldLabel.Text = 'Transition Reversals';
            app.TransitionReversalsEditField = uieditfield(app.StrainLifeTab, 'numeric');
            app.StrainLifeDamageTab = uitab(app.TabGroup);
            app.StrainLifeDamageTab.Title = 'Strain Life Damage';
            app.ComputeButton_3 = uibutton(app.StrainLifeDamageTab, 'push');
            app.ComputeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_3Pushed, true);
            app.ComputeButton_3.Text = 'Compute';
            app.CumulativeDamageTWISTEditField_2Label = uilabel(app.StrainLifeDamageTab);
            app.CumulativeDamageTWISTEditField_2Label.Text = {'Cumulative Damage'; '(TWIST)'};
            app.CumulativeDamageTWISTEditField_2 = uieditfield(app.StrainLifeDamageTab, 'numeric');
            app.RepetitionsEditField_2Label = uilabel(app.StrainLifeDamageTab);
            app.RepetitionsEditField_2Label.Text = 'Repetitions';
            app.RepetitionsEditField_2 = uieditfield(app.StrainLifeDamageTab, 'numeric');
            app.BlocksLabel_2 = uilabel(app.StrainLifeDamageTab);
            app.BlocksLabel_2.Text = 'Blocks';
            app.CyclestoFailureEditField_2Label = uilabel(app.StrainLifeDamageTab);
            app.CyclestoFailureEditField_2Label.Text = 'Cycles to Failure';
            app.CyclestoFailureEditField_2 = uieditfield(app.StrainLifeDamageTab, 'numeric');
            app.CyclesLabel_4 = uilabel(app.StrainLifeDamageTab);
            app.CyclesLabel_4.Text = 'Cycles';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end